classdef Buffons_Needle_Modified_Version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BuffonsExperimentModifiedVersionLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SquaresTab                     matlab.ui.container.Tab
        Slider                         matlab.ui.control.Slider
        GenerateButton_2               matlab.ui.control.Button
        NumofSquaresEditFieldLabel     matlab.ui.control.Label
        NumofSquaresEditField          matlab.ui.control.NumericEditField
        NumofPlanksEditFieldLabel      matlab.ui.control.Label
        NumofPlanksEditField           matlab.ui.control.NumericEditField
        PercentageLabel                matlab.ui.control.Label
        ofsquaresLabel                 matlab.ui.control.Label
        shownLabel                     matlab.ui.control.Label
        SelectEstimationDropDownLabel  matlab.ui.control.Label
        SelectEstimationDropDown       matlab.ui.control.DropDown
        IntersectedButtonGroup         matlab.ui.container.ButtonGroup
        BlueButton_2                   matlab.ui.control.RadioButton
        PurpleButton                   matlab.ui.control.RadioButton
        RedButton_2                    matlab.ui.control.RadioButton
        NonIntersectedButtonGroup      matlab.ui.container.ButtonGroup
        BabyBlueButton                 matlab.ui.control.RadioButton
        YellowButton                   matlab.ui.control.RadioButton
        GreenButton_2                  matlab.ui.control.RadioButton
        SquaresColourLabel             matlab.ui.control.Label
        FontSizeEditFieldLabel         matlab.ui.control.Label
        FontSizeEditField              matlab.ui.control.NumericEditField
        EstimatedtextLabel             matlab.ui.control.Label
        ColourDropDownLabel            matlab.ui.control.Label
        ColourDropDown                 matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NeedesTab                      matlab.ui.container.Tab
        NumofNeedlesEditField_3Label   matlab.ui.control.Label
        NumofNeedlesEditField_3        matlab.ui.control.NumericEditField
        NumofTilesLabel                matlab.ui.control.Label
        NumofsquaretilesEditField      matlab.ui.control.NumericEditField
        GenerateButton                 matlab.ui.control.Button
        Slider_3                       matlab.ui.control.Slider
        PercentageLabel_2              matlab.ui.control.Label
        ofneedlesLabel                 matlab.ui.control.Label
        shownLabel_3                   matlab.ui.control.Label
        NeddlessizeButtonGroup         matlab.ui.container.ButtonGroup
        FixedButton                    matlab.ui.control.RadioButton
        MixtureButton                  matlab.ui.control.RadioButton
        EstimatedtextLabel_2           matlab.ui.control.Label
        FontSizeEditField_2Label       matlab.ui.control.Label
        FontSizeEditField_2            matlab.ui.control.NumericEditField
        ColourDropDown_2Label          matlab.ui.control.Label
        ColourDropDown_2               matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        NeedlesAlignedEditFieldLabel   matlab.ui.control.Label
        NeedlesAlignedEditField        matlab.ui.control.NumericEditField
        UIAxes_3                       matlab.ui.control.UIAxes
        AnthosKountourisLabel          matlab.ui.control.Label
        C1945047Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        d; %Distance between the parallel planks of wood
        len; % length of the needle 
        n; %Number off needles/squares thrown
        fd = 100; %Whole distance of the floor(boundaries)
        par = 10;%number of planks
        diagonal; 
        t = 100; %number of tiles
        pososto = 0.001;% percentage of squares shown
        pososto1 = 0.001;% percentage of needles shown
        katse = 3; % closest slope needles
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            app.d = app.fd / (app.par+1); 
            app.len = sqrt((app.d^2)/2);
            app.n = app.NumofSquaresEditField.Value;
            app.diagonal = sqrt((app.len^2)*2);
            
            x = [-app.len/2 app.len/2 app.len/2 -app.len/2 ];       %
                                                                    % This is where the square is made  
            y = [app.len/2 app.len/2 -app.len/2 -app.len/2 ];       %

            
            k=sqrt(2*app.len^2);             % k minimum displayed distance
            m=app.fd-k;                      %
            
            cp = (m-k).*rand(app.n,2)+k;   %Random center points
            
            theta = rand(app.n,1).*90; %random angles
            x1= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));   %rotation
            y1= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1));  %rotation
            
            x2 = x1(1:app.n,:)+cp(1:app.n,1); %rotated squares plus the shift
            y2 = y1(1:app.n,:)+cp(1:app.n,2); %rotated squares plus the shift
            
            planks = app.d:app.d:app.fd-app.d; %vector of the parallel parallen planks of wood
            
            ax = app.UIAxes;
            ax.XAxis.Limits = [0 app.fd]; %x Boundaries
            ax.YAxis.Limits = [0 app.fd]; %y Boundaries
            cla(ax); %clear plot
            
            intersect = 0; %counter
            xinter = [0 0 0 0]; %xintersections
            yinter = [0 0 0 0]; %yintersections
            
            inter=[];
            
            for i=1:length(planks)
                xline(ax,planks(i));
                R1 = find((min(x2,[],2)<=planks(i) & (max(x2,[],2) >=planks(i))));
                inter = [inter R1'];
                R2 = x2(R1,:);
                R3 = y2(R1,:);
                xinter((size(xinter,1)+1):(size(xinter,1)+size(R2,1)),:) = R2;
                yinter((size(yinter,1)+1):(size(yinter,1)+size(R3,1)),:) = R3;
                intersect = intersect + length(R1);
            end
                
            a1 = xinter'; %x of squares that intersect
            a2 = yinter'; %y of squares that intersect    
            flowa = size(a1,2)/(app.n*app.pososto/100); %less squares with percentage
            
            x2(inter,:) = [];
            y2(inter,:) = [];
            b1 = x2'; %x of squares that don't intersect
            b2 = y2'; %y of squares that don't intersect
            flowb = size(b1,2)/(app.n*app.pososto/100); %less squares with percentage
            
            if app.IntersectedButtonGroup.SelectedObject.Text == "Blue"
                first_patch = patch(ax,a1(:,1:flowa:size(a1,2)),a2(:,1:flowa:size(a2,2)),'blue','LineStyle','-','EdgeColor',[0 0 1],'FaceAlpha',0);
            else if app.IntersectedButtonGroup.SelectedObject.Text == "Purple"
                first_patch = patch(ax,a1(:,1:flowa:size(a1,2)),a2(:,1:flowa:size(a2,2)),'','LineStyle','-','EdgeColor',[0.4940, 0.1840, 0.5560],'FaceAlpha',0);
                else if app.IntersectedButtonGroup.SelectedObject.Text == "Red"
                    first_patch = patch(ax,a1(:,1:flowa:size(a1,2)),a2(:,1:flowa:size(a2,2)),'red','LineStyle','-','EdgeColor',[1 0 0],'FaceAlpha',0);
                    end
                end
            end  
            
            if app.NonIntersectedButtonGroup.SelectedObject.Text == "Baby Blue"
                second_patch = patch(ax,b1(:,1:flowb:size(b1,2)),b2(:,1:flowb:size(b2,2)),'','LineStyle','-','EdgeColor',[0.3010, 0.7450, 0.9330],'FaceAlpha',0);
            else if app.NonIntersectedButtonGroup.SelectedObject.Text == "Yellow"
                second_patch = patch(ax,b1(:,1:flowb:size(b1,2)),b2(:,1:flowb:size(b2,2)),'','LineStyle','-','EdgeColor',[0.9290, 0.6940, 0.1250],'FaceAlpha',0);
                else if app.NonIntersectedButtonGroup.SelectedObject.Text == "Green"
                    second_patch = patch(ax,b1(:,1:flowb:size(b1,2)),b2(:,1:flowb:size(b2,2)),'green','LineStyle','-','EdgeColor',[0 1 0],'FaceAlpha',0);
                    end
                end
            end
            
           
            probabilityA = intersect/app.n; % The probability of the intersected squares
            probabilityB = 4*(2*app.len-app.diagonal)/(pi*app.d); %probability a line intersects two consecutive sides of the polygon.
             
            if app.SelectEstimationDropDown.Value == "Pi"
                estimated_pi = (4*app.len)/(probabilityA*app.d); % estimation of pi calculated by the formulae
                app.Label.Text = "Estimated Pi: "; app.Label_2.Text = (string(estimated_pi));
                app.Label.Visible = 'on'; app.Label_2.Visible = 'on';
                
            else if app.SelectEstimationDropDown.Value == "√2"
                estimated_sq2 = 2-probabilityB/probabilityA %estimation of sqrt(2) calculated by the formulae
                app.Label.Text = "Estimated √2: "; app.Label_2.Text = (string(estimated_sq2));
                app.Label.Visible = 'on'; app.Label_2.Visible = 'on';
                end
           end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.d = app.fd/sqrt(app.t);
           
            if app.NeddlessizeButtonGroup.SelectedObject.Text == "Fixed"
                app.len = app.d;
                app.n = app.NumofNeedlesEditField_3.Value;
            
                x = [-app.len/2 app.len/2];     %
                                                % This is where the needle is made  
                y = [0 0];                      %
                
                
                k=app.len;             % k minimum displayed distance
                m=app.fd-k;            %
                
                ax = app.UIAxes_3;
                ax.XAxis.Limits = [0 app.fd]; %x Boundaries
                ax.YAxis.Limits = [0 app.fd]; %y Boundaries
                cla(ax); %clear plot
                
                cp = (m-k).*rand(app.n,2)+k;   %Random center points

                planks = app.d:app.d:app.fd-app.d; %vector of parallel planks
                
                theta = rand(app.n,1).*180; %random angles
                x1= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));  %rotation
                y1= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1)); %rotation
                
                x2 = x1(1:app.n,:)+cp(1:app.n,1); %rotated squares plus the shift
                y2 = y1(1:app.n,:)+cp(1:app.n,2); %rotated squares plus the shift
                
    
                intersect = 0; %counter
                xinter = [0 0]; %xintersections
                yinter = [0 0]; %yintersections
                
                inter=[];
            
                for i=1:length(planks)
                     xline(ax,planks(i));%printing x lines
                     
                     R1 = find((min(x2,[],2)<=planks(i) & (max(x2,[],2) >=planks(i)))); %finding if the needles intersect the x planks
                     inter = [inter R1'];
                     
                     R2 = x2(R1,:);
                     R3 = y2(R1,:);
                     
                     xinter((size(xinter,1)+1):(size(xinter,1)+size(R2,1)),:) = R2;
                     yinter((size(yinter,1)+1):(size(yinter,1)+size(R3,1)),:) = R3;
                     
                     
                     yline(ax,planks(i));%printing y lines
                     
                     M1 = find((min(y2,[],2)<=planks(i) & (max(y2,[],2) >=planks(i))));%finding if the needles intersect the y planks
                     inter = [inter M1'];
                     
                     M2 = x2(M1,:);
                     M3 = y2(M1,:);
                     
                     xinter((size(xinter,1)+1):(size(xinter,1)+size(M2,1)),:) = M2;
                     yinter((size(yinter,1)+1):(size(yinter,1)+size(M3,1)),:) = M3;
            
                 
                end
            
                a1 = xinter'; %x of needles that intersect
                a2 = yinter'; %y of needles that intersect
                intersect = length(unique(inter.','rows').');
                     
                flowa = size(a1,2)/(app.n*app.pososto1/100); %less needles with percentage
%                 
                x2(inter,:) = [];
                y2(inter,:) = [];
                c1 = x2';
                c2 = y2';
                flowb = size(c1,2)/(app.n*app.pososto1/100); %less needles with percentage
                
                kk1 = unique(a1.','rows',"stable").';
                pp1 = unique(a2.','rows',"stable").';
                
                kk2 = c1;
                pp2 = c2;
                
                kk1(:,1) = [];
                pp1(:,1) = [];
                
                xx = kk1(:,1:flowa:size(kk1,2)); % x of intersected needles(less shown based on percentage)
                yy = pp1(:,1:flowa:size(pp1,2)); % y of intersected needles(less shown based on percentage)
        
                xx1 = kk2(:,1:flowb:size(kk2,2)); % x of non-intersected needles(less shown based on percentage)
                yy1 = pp2(:,1:flowb:size(pp2,2)); % y of non-intersected needles(less shown based on percentage)
            
                
                hold(ax,'on');
                
                H1 = plot(ax,xx,yy,'Color',[0.4940, 0.1840, 0.5560]);                     
                H2 = plot(ax,xx1,yy1,'Color',[0 1 0]); 

                H = gobjects(1,length(H1)+length(H2));
                H(1:length(H1)) = H1;
                H(length(H1)+1:length(H1)+length(H2)) =  H2;
               
                hold(ax,'off');
                
            
                set(H, 'ButtonDownFcn', {@LineSelected, H}); %calling the function
            
                probabilityA = intersect/app.n; % The probability of the intersected needles
                estimated_pi = (4*app.len*app.d-app.len^2)/(probabilityA*app.d^2); % estimation of pi calculated by the formulae
                app.Label_3.Text = "Estimated Pi: "; app.Label_4.Text = (string(estimated_pi));
                app.Label_3.Visible = 'on'; app.Label_4.Visible = 'on';


            else if app.NeddlessizeButtonGroup.SelectedObject.Text == "Mixture"
                    app.n = app.NumofNeedlesEditField_3.Value;
                    app.len = app.d.*rand(app.n,1);
                    

                    x = [-app.len(:,1)/2 app.len(:,1)/2];     %
                                                              % This is where the needle is made  
                    y = [0 0];                                %
                    
                    
                    k=app.len(:,1);             % k minimum displayed distance
                    m=app.fd-k;                 %
              
                    
                    ax = app.UIAxes_3;
                    ax.XAxis.Limits = [0 app.fd]; %x Boundaries
                    ax.YAxis.Limits = [0 app.fd]; %y Boundaries
                    cla(ax); %clear plot
                    
                    cp = (m-k).*rand(app.n,2)+k;   %Random center points
        
                    planks = app.d:app.d:app.fd-app.d; %vector of parallel planks
                    
                    theta = rand(app.n,1).*180; %random angles
                    x1= x.*cosd(theta(:,1)) + y.*sind(theta(:,1));  %rotation
                    y1= -x.*sind(theta(:,1)) + y.*cosd(theta(:,1)); %rotation
                    
                    x2 = x1(1:app.n,:)+cp(1:app.n,1); %rotated needles plus the shift
                    y2 = y1(1:app.n,:)+cp(1:app.n,2); %rotated needles plus the shift
                    
        
                    intersect = 0; %counter
                    xinter = [0 0]; %xintersections
                    yinter = [0 0]; %yintersections
                    
                    inter=[];
                    
                    for i=1:length(planks)
                         xline(ax,planks(i));%ploting x lines
                         
                         R1 = find((min(x2,[],2)<=planks(i) & (max(x2,[],2) >=planks(i)))); %finding if the needles intersect the x planks
                         inter = [inter R1'];
                         
                         R2 = x2(R1,:);
                         R3 = y2(R1,:);
                         
                         xinter((size(xinter,1)+1):(size(xinter,1)+size(R2,1)),:) = R2;
                         yinter((size(yinter,1)+1):(size(yinter,1)+size(R3,1)),:) = R3;
                         
                         
                         yline(ax,planks(i));%ploting y lines
                 
                         M1 = find((min(y2,[],2)<=planks(i) & (max(y2,[],2) >=planks(i))));%finding if the needles intersect the y planks
                         inter = [inter M1'];
                         
                         M2 = x2(M1,:);
                         M3 = y2(M1,:);
                         
                         xinter((size(xinter,1)+1):(size(xinter,1)+size(M2,1)),:) = M2;
                         yinter((size(yinter,1)+1):(size(yinter,1)+size(M3,1)),:) = M3;
                    
                         
                    end
                    
                    a1 = xinter'; %x of needles that intersect
                    a2 = yinter'; %y of needles that intersect
                    intersect = length(unique(inter.','rows').');
                         
                    flowa = size(a1,2)/(app.n*app.pososto1/100);


                    x2(inter,:) = [];
                    y2(inter,:) = [];
                    c1 = x2';
                    c2 = y2';
                    flowb = size(c1,2)/(app.n*app.pososto1/100);
                    
                    
                    kk1 = unique(a1.','rows',"stable").';
                    pp1 = unique(a2.','rows',"stable").';
                    
                    kk2 = c1;
                    pp2 = c2;
                    
                    kk1(:,1) = [];
                    pp1(:,1) = [];
                    
                    xx = kk1(:,1:flowa:size(kk1,2)); % x of intersected needles(less shown based on percentage)
                    yy = pp1(:,1:flowa:size(pp1,2)); % y of intersected needles(less shown based on percentage)
            
                    xx1 = kk2(:,1:flowb:size(kk2,2)); % x of non-intersected needles(less shown based on percentage)
                    yy1 = pp2(:,1:flowb:size(pp2,2)); % y of non-intersected needles(less shown based on percentage)
                
                    
                    hold(ax,'on');
                    
                    H1 = plot(ax,xx,yy,'Color',[0.4940, 0.1840, 0.5560]);                     
                    H2 = plot(ax,xx1,yy1,'Color',[0 1 0]); 
    
                    H = gobjects(1,length(H1)+length(H2));
                    H(1:length(H1)) = H1;
                    H(length(H1)+1:length(H1)+length(H2)) =  H2;
                   
                    hold(ax,'off');
                
            
                    set(H, 'ButtonDownFcn', {@LineSelected, H}); %calling the function
                   
                    probabilityA = intersect/app.n; % The probability of the intersected needles
                    estimated_pi = (4*app.len.*app.d-app.len.^2)/(probabilityA*app.d^2); % estimation of pis calculated by the formulae for every length of needles
                    avarage_estimated_pi=sum(estimated_pi)/app.n; %avarage pi for all the lengths of needles
                    app.Label_3.Text = "Estimated Pi: "; app.Label_4.Text = (string(avarage_estimated_pi));
                    app.Label_3.Visible = 'on'; app.Label_4.Visible = 'on';
            end
            end
                
            
              function LineSelected(ObjectH, ~, H)
                set(ObjectH, 'Color', [1 0 0],'LineWidth',2.5)
                set(H(H ~= ObjectH), 'Color', [0.4940, 0.1840, 0.5560],'LineWidth',0.5) ;
                x = ObjectH.XData;
                y = ObjectH.YData;
       
                slopef = (y(2)-y(1))/(x(2)-x(1)); %slope of the selected needle by the user
                pinakas = zeros(length(H),7); 
                
                for r=1:length(H)
                    prosorino = H(1,r).XData;
                    pinakas(r,1) = prosorino(1);
                    pinakas(r,2) = prosorino(2);
                    prosorino = H(1,r).YData;
                    pinakas(r,3) = prosorino(1);
                    pinakas(r,4) = prosorino(2);
                    pinakas(r,5) = (pinakas(r,4)-pinakas(r,3))/(pinakas(r,2)-pinakas(r,1));
                    pinakas(r,6) = abs(pinakas(r,5)-slopef);
                    pinakas(r,7) = r;
                end
                
                pinakas(1,:) = [];
                  
                ponima = sortrows(pinakas,6);

                for i=2:(app.katse+1) %closest slope needles
                    set(H(ponima(i,7)),'Color',[0 1 1],'LineWidth',2.5)
                end
              end 
            
        end

        % Value changed function: NumofSquaresEditField
        function NumofSquaresEditFieldValueChanged(app, event)
            value = app.NumofSquaresEditField.Value;
            app.n = value;
        end

        % Value changed function: NumofPlanksEditField
        function NumofPlanksEditFieldValueChanged(app, event)
            value = app.NumofPlanksEditField.Value;
            app.par = value;
        end

        % Value changed function: NumofNeedlesEditField_3
        function NumofNeedlesEditField_3ValueChanged(app, event)
            value = app.NumofNeedlesEditField_3.Value;
            app.n = value;
        end

        % Value changed function: NumofsquaretilesEditField
        function NumofsquaretilesEditFieldValueChanged(app, event)
            value = app.NumofsquaretilesEditField.Value;
            app.t = value;
        end

        % Callback function
        function NumofTilesEditFieldValueChanged(app, event)
            value = app.NumofTilesEditField.Value;
            app.t = value;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.pososto = changingValue;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.pososto1 = changingValue;
        end

        % Value changed function: SelectEstimationDropDown
        function SelectEstimationDropDownValueChanged(app, event)
            value = app.SelectEstimationDropDown.Value;   
        end

        % Selection changed function: IntersectedButtonGroup
        function IntersectedButtonGroupSelectionChanged(app, event)
            selectedButton = app.IntersectedButtonGroup.SelectedObject;
        end

        % Selection changed function: NonIntersectedButtonGroup
        function NonIntersectedButtonGroupSelectionChanged(app, event)
            selectedButton = app.NonIntersectedButtonGroup.SelectedObject;
        end

        % Selection changed function: NeddlessizeButtonGroup
        function NeddlessizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.NeddlessizeButtonGroup.SelectedObject;
  
        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            app.Label_2.FontSize = value;
            app.Label.FontSize = value;
        end

        % Value changed function: ColourDropDown
        function ColourDropDownValueChanged(app, event)
            value = app.ColourDropDown.Value;
            app.Label_2.FontColor = value;
        end

        % Value changed function: FontSizeEditField_2
        function FontSizeEditField_2ValueChanged(app, event)
            value = app.FontSizeEditField_2.Value;
            app.Label_3.FontSize = value;
            app.Label_4.FontSize = value;
        end

        % Value changed function: ColourDropDown_2
        function ColourDropDown_2ValueChanged(app, event)
            value = app.ColourDropDown_2.Value;
            app.Label_4.FontColor = value;
        end

        % Value changed function: NeedlesAlignedEditField
        function NeedlesAlignedEditFieldValueChanged(app, event)
            value = app.NeedlesAlignedEditField.Value;
            app.katse = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BuffonsExperimentModifiedVersionLabel = uilabel(app.UIFigure);
            app.BuffonsExperimentModifiedVersionLabel.Text = 'Buffon''s Experiment Modified Version';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SquaresTab = uitab(app.TabGroup);
            app.SquaresTab.Title = 'Squares';
            app.Slider = uislider(app.SquaresTab);
            app.Slider.Limits = [0.001 100];
            app.Slider.MajorTicks = [10 20 30 40 50 60 70 80 90 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [0.001 2.001 4.001 6.001 8.001 10.001 12.001 14.001 16.001 18.001 20.001 22.001 24.001 26.001 28.001 30.001 32.001 34.001 36.001 38.001 40.001 42.001 44.001 46.001 48.001 50.001 52.001 54.001 56.001 58.001 60.001 62.001 64.001 66.001 68.001 70.001 72.001 74.001 76.001 78.001 80.001 82.001 84.001 86.001 88.001 90.001 92.001 94.001 96.001 98.001 100];
            app.Slider.Value = 0.001;
            app.GenerateButton_2 = uibutton(app.SquaresTab, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.NumofSquaresEditFieldLabel = uilabel(app.SquaresTab);
            app.NumofSquaresEditFieldLabel.Text = 'Num of Squares';
            app.NumofSquaresEditField = uieditfield(app.SquaresTab, 'numeric');
            app.NumofSquaresEditField.Limits = [1000000 Inf];
            app.NumofSquaresEditField.ValueDisplayFormat = '%d';
            app.NumofSquaresEditField.ValueChangedFcn = createCallbackFcn(app, @NumofSquaresEditFieldValueChanged, true);
            app.NumofSquaresEditField.Value = 1000000;
            app.NumofPlanksEditFieldLabel = uilabel(app.SquaresTab);
            app.NumofPlanksEditFieldLabel.Text = 'Num of Planks';
            app.NumofPlanksEditField = uieditfield(app.SquaresTab, 'numeric');
            app.NumofPlanksEditField.Limits = [10 Inf];
            app.NumofPlanksEditField.ValueDisplayFormat = '%d';
            app.NumofPlanksEditField.ValueChangedFcn = createCallbackFcn(app, @NumofPlanksEditFieldValueChanged, true);
            app.NumofPlanksEditField.Value = 10;
            app.PercentageLabel = uilabel(app.SquaresTab);
            app.PercentageLabel.Text = 'Percentage';
            app.ofsquaresLabel = uilabel(app.SquaresTab);
            app.ofsquaresLabel.Text = 'of squares';
            app.shownLabel = uilabel(app.SquaresTab);
            app.shownLabel.Text = 'shown';
            app.SelectEstimationDropDownLabel = uilabel(app.SquaresTab);
            app.SelectEstimationDropDownLabel.Text = 'Select Estimation';
            app.SelectEstimationDropDown = uidropdown(app.SquaresTab);
            app.SelectEstimationDropDown.Items = {'Pi', '√2'};
            app.SelectEstimationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEstimationDropDownValueChanged, true);
            app.SelectEstimationDropDown.Value = 'Pi';
            app.IntersectedButtonGroup = uibuttongroup(app.SquaresTab);
            app.IntersectedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntersectedButtonGroupSelectionChanged, true);
            app.IntersectedButtonGroup.Title = 'Intersected';
            app.BlueButton_2 = uiradiobutton(app.IntersectedButtonGroup);
            app.BlueButton_2.Text = 'Blue';
            app.BlueButton_2.Value = true;
            app.PurpleButton = uiradiobutton(app.IntersectedButtonGroup);
            app.PurpleButton.Text = 'Purple';
            app.RedButton_2 = uiradiobutton(app.IntersectedButtonGroup);
            app.RedButton_2.Text = 'Red';
            app.NonIntersectedButtonGroup = uibuttongroup(app.SquaresTab);
            app.NonIntersectedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NonIntersectedButtonGroupSelectionChanged, true);
            app.NonIntersectedButtonGroup.Title = 'Non-Intersected';
            app.BabyBlueButton = uiradiobutton(app.NonIntersectedButtonGroup);
            app.BabyBlueButton.Text = 'Baby Blue';
            app.BabyBlueButton.Value = true;
            app.YellowButton = uiradiobutton(app.NonIntersectedButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.GreenButton_2 = uiradiobutton(app.NonIntersectedButtonGroup);
            app.GreenButton_2.Text = 'Green';
            app.SquaresColourLabel = uilabel(app.SquaresTab);
            app.SquaresColourLabel.Text = 'Squares Colour';
            app.FontSizeEditFieldLabel = uilabel(app.SquaresTab);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.SquaresTab, 'numeric');
            app.FontSizeEditField.Limits = [0 30];
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.FontSizeEditField.Value = 12;
            app.EstimatedtextLabel = uilabel(app.SquaresTab);
            app.EstimatedtextLabel.Text = 'Estimated text';
            app.ColourDropDownLabel = uilabel(app.SquaresTab);
            app.ColourDropDownLabel.Text = 'Colour';
            app.ColourDropDown = uidropdown(app.SquaresTab);
            app.ColourDropDown.Items = {'Black', 'Blue', 'Yellow', 'Cyan', 'Red', 'Green'};
            app.ColourDropDown.ValueChangedFcn = createCallbackFcn(app, @ColourDropDownValueChanged, true);
            app.ColourDropDown.Value = 'Black';
            app.Label = uilabel(app.SquaresTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.Label_2 = uilabel(app.SquaresTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.UIAxes = uiaxes(app.SquaresTab);
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.NeedesTab = uitab(app.TabGroup);
            app.NeedesTab.Title = 'Needes';
            app.NumofNeedlesEditField_3Label = uilabel(app.NeedesTab);
            app.NumofNeedlesEditField_3Label.Text = 'Num of Needles';
            app.NumofNeedlesEditField_3 = uieditfield(app.NeedesTab, 'numeric');
            app.NumofNeedlesEditField_3.Limits = [1000000 Inf];
            app.NumofNeedlesEditField_3.ValueDisplayFormat = '%d';
            app.NumofNeedlesEditField_3.ValueChangedFcn = createCallbackFcn(app, @NumofNeedlesEditField_3ValueChanged, true);
            app.NumofNeedlesEditField_3.Value = 1000000;
            app.NumofTilesLabel = uilabel(app.NeedesTab);
            app.NumofTilesLabel.Text = 'Num of Tiles';
            app.NumofsquaretilesEditField = uieditfield(app.NeedesTab, 'numeric');
            app.NumofsquaretilesEditField.Limits = [100 Inf];
            app.NumofsquaretilesEditField.ValueDisplayFormat = '%d';
            app.NumofsquaretilesEditField.ValueChangedFcn = createCallbackFcn(app, @NumofsquaretilesEditFieldValueChanged, true);
            app.NumofsquaretilesEditField.Value = 100;
            app.GenerateButton = uibutton(app.NeedesTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Slider_3 = uislider(app.NeedesTab);
            app.Slider_3.Limits = [0.001 0.1];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.Value = 0.001;
            app.PercentageLabel_2 = uilabel(app.NeedesTab);
            app.PercentageLabel_2.Text = 'Percentage';
            app.ofneedlesLabel = uilabel(app.NeedesTab);
            app.ofneedlesLabel.Text = 'of needles';
            app.shownLabel_3 = uilabel(app.NeedesTab);
            app.shownLabel_3.Text = 'shown';
            app.NeddlessizeButtonGroup = uibuttongroup(app.NeedesTab);
            app.NeddlessizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NeddlessizeButtonGroupSelectionChanged, true);
            app.NeddlessizeButtonGroup.Title = 'Neddles size';
            app.FixedButton = uiradiobutton(app.NeddlessizeButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.MixtureButton = uiradiobutton(app.NeddlessizeButtonGroup);
            app.MixtureButton.Text = 'Mixture';
            app.EstimatedtextLabel_2 = uilabel(app.NeedesTab);
            app.EstimatedtextLabel_2.Text = 'Estimated text';
            app.FontSizeEditField_2Label = uilabel(app.NeedesTab);
            app.FontSizeEditField_2Label.Text = 'Font Size';
            app.FontSizeEditField_2 = uieditfield(app.NeedesTab, 'numeric');
            app.FontSizeEditField_2.Limits = [0 30];
            app.FontSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditField_2ValueChanged, true);
            app.FontSizeEditField_2.Value = 12;
            app.ColourDropDown_2Label = uilabel(app.NeedesTab);
            app.ColourDropDown_2Label.Text = 'Colour';
            app.ColourDropDown_2 = uidropdown(app.NeedesTab);
            app.ColourDropDown_2.Items = {'Black', 'Blue', 'Yellow', 'Cyan', 'Red', 'Green'};
            app.ColourDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColourDropDown_2ValueChanged, true);
            app.ColourDropDown_2.Value = 'Black';
            app.Label_3 = uilabel(app.NeedesTab);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.NeedesTab);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '';
            app.NeedlesAlignedEditFieldLabel = uilabel(app.NeedesTab);
            app.NeedlesAlignedEditFieldLabel.Text = 'Needles Aligned';
            app.NeedlesAlignedEditField = uieditfield(app.NeedesTab, 'numeric');
            app.NeedlesAlignedEditField.Limits = [0 Inf];
            app.NeedlesAlignedEditField.ValueChangedFcn = createCallbackFcn(app, @NeedlesAlignedEditFieldValueChanged, true);
            app.NeedlesAlignedEditField.Value = 3;
            app.UIAxes_3 = uiaxes(app.NeedesTab);
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = '';
            app.AnthosKountourisLabel = uilabel(app.UIFigure);
            app.AnthosKountourisLabel.Text = 'Anthos Kountouris';
            app.C1945047Label = uilabel(app.UIFigure);
            app.C1945047Label.Text = 'C1945047';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Buffons_Needle_Modified_Version

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end